classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        ManualModeTab              matlab.ui.container.Tab
        XHomeLimitLamp             matlab.ui.control.Lamp
        XHomeLimitLampLabel        matlab.ui.control.Label
        ZHomeLimitLamp             matlab.ui.control.Lamp
        ZHomeLimitLampLabel        matlab.ui.control.Label
        YHomeLimitLamp             matlab.ui.control.Lamp
        YHomeLimitLampLabel        matlab.ui.control.Label
        AutoHomeButton             matlab.ui.control.StateButton
        HomingRoutineLabel         matlab.ui.control.Label
        EnableMotorsButton         matlab.ui.control.StateButton
        Image_2                    matlab.ui.control.Image
        MovetoPositionLabel        matlab.ui.control.Label
        coordinateZEditField       matlab.ui.control.NumericEditField
        coordinateZEditFieldLabel  matlab.ui.control.Label
        coordinateYEditField       matlab.ui.control.NumericEditField
        coordinateYEditFieldLabel  matlab.ui.control.Label
        RUNButton                  matlab.ui.control.StateButton
        coordinateXEditField       matlab.ui.control.NumericEditField
        coordinateXEditFieldLabel  matlab.ui.control.Label
        COMPortDropDown            matlab.ui.control.DropDown
        COMPortDropDownLabel       matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        AutomaticModeTab           matlab.ui.container.Tab
        ZP_ZEditField              matlab.ui.control.NumericEditField
        ZP_ZEditFieldLabel         matlab.ui.control.Label
        ZP_YEditField              matlab.ui.control.NumericEditField
        ZP_YEditFieldLabel         matlab.ui.control.Label
        ZP_XEditField              matlab.ui.control.NumericEditField
        ZP_XEditFieldLabel         matlab.ui.control.Label
        SETButton                  matlab.ui.control.Button
        SetupLabel                 matlab.ui.control.Label
        ElapsedTimeLabel           matlab.ui.control.Label
        SimulationTimeEditField    slrealtime.ui.control.SimulationTimeEditField
        StartButton                matlab.ui.control.Button
        ReadGcodeButton            matlab.ui.control.Button
        Image_4                    matlab.ui.control.Image
        Image6                     matlab.ui.control.Image
        InfoTab                    matlab.ui.container.Tab
        Label_3                    matlab.ui.control.Label
        AboutLabel                 matlab.ui.control.Label
        Image5                     matlab.ui.control.Image
        Label_2                    matlab.ui.control.Label
        AutomaticmodeLabel         matlab.ui.control.Label
        Image4_2                   matlab.ui.control.Image
        Label                      matlab.ui.control.Label
        ManualModeLabel            matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
    end

    properties (Access = public)
        Connect = 0;
        Command = '';
        FileName = 0;
        PathName = 0;
        STOP = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          disp('Initialization of the application')
          
        end

        % Clicked callback: COMPortDropDown
        function SelectCOMDropDownClicked(app, event)
%             app.Connect = 1;
%             item = event.InteractionInformation.Item;
%             disp("number "+ string(app.COMPortDropDown.Value));
        end

        % Value changed function: COMPortDropDown
        function SelectCOMDropDownValueChanged(app, event)
            value = app.COMPortDropDown.Value;
            app.Connect = 1;
%             disp('hi')
%             disp('number '+ str(value));
        end

        % Value changed function: coordinateXEditField
        function coordinateXEditFieldValueChanged(app, event)
            value = app.coordinateXEditField.Value;
            
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            %value = app.RUNButton.Value;
            app.Command = 'RUN';
        end

        % Value changed function: coordinateYEditField
        function coordinateYEditFieldValueChanged(app, event)
            value = app.coordinateYEditField.Value;
            
        end

        % Value changed function: coordinateZEditField
        function coordinateZEditFieldValueChanged(app, event)
            value = app.coordinateZEditField.Value;
            
        end

        % Button pushed function: ReadGcodeButton
        function ReadGcodeButtonPushed(app, event)
           [app.FileName app.PathName] = uigetfile('*.nc','Select the toolpath code file');
        end

        % Value changed function: AutoHomeButton
        function AutoHomeButtonValueChanged(app, event)
            %value = app.AutoHomeButton.Value;
            app.Command = 'HOME';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Command = 'START';
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
            app.STOP = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualModeTab = uitab(app.TabGroup);
            app.ManualModeTab.Title = 'Manual Mode';
            app.Image = uiimage(app.ManualModeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Sem Título.png');
            app.COMPortDropDownLabel = uilabel(app.ManualModeTab);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ManualModeTab);
            app.COMPortDropDown.Items = {'', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCOMDropDownValueChanged, true);
            app.COMPortDropDown.ClickedFcn = createCallbackFcn(app, @SelectCOMDropDownClicked, true);
            app.COMPortDropDown.Value = '';
            app.coordinateXEditFieldLabel = uilabel(app.ManualModeTab);
            app.coordinateXEditFieldLabel.Text = 'coordinate X';
            app.coordinateXEditField = uieditfield(app.ManualModeTab, 'numeric');
            app.coordinateXEditField.ValueChangedFcn = createCallbackFcn(app, @coordinateXEditFieldValueChanged, true);
            app.RUNButton = uibutton(app.ManualModeTab, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Text = 'RUN';
            app.coordinateYEditFieldLabel = uilabel(app.ManualModeTab);
            app.coordinateYEditFieldLabel.Text = 'coordinate Y';
            app.coordinateYEditField = uieditfield(app.ManualModeTab, 'numeric');
            app.coordinateYEditField.ValueChangedFcn = createCallbackFcn(app, @coordinateYEditFieldValueChanged, true);
            app.coordinateZEditFieldLabel = uilabel(app.ManualModeTab);
            app.coordinateZEditFieldLabel.Text = 'coordinate Z';
            app.coordinateZEditField = uieditfield(app.ManualModeTab, 'numeric');
            app.coordinateZEditField.ValueChangedFcn = createCallbackFcn(app, @coordinateZEditFieldValueChanged, true);
            app.MovetoPositionLabel = uilabel(app.ManualModeTab);
            app.MovetoPositionLabel.Text = 'Move to Position';
            app.Image_2 = uiimage(app.ManualModeTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Sem Título.png');
            app.EnableMotorsButton = uibutton(app.ManualModeTab, 'state');
            app.EnableMotorsButton.Text = 'Enable Motors';
            app.HomingRoutineLabel = uilabel(app.ManualModeTab);
            app.HomingRoutineLabel.Text = 'Homing Routine';
            app.AutoHomeButton = uibutton(app.ManualModeTab, 'state');
            app.AutoHomeButton.ValueChangedFcn = createCallbackFcn(app, @AutoHomeButtonValueChanged, true);
            app.AutoHomeButton.Text = 'Auto Home';
            app.YHomeLimitLampLabel = uilabel(app.ManualModeTab);
            app.YHomeLimitLampLabel.Text = 'Y Home Limit';
            app.YHomeLimitLamp = uilamp(app.ManualModeTab);
            app.ZHomeLimitLampLabel = uilabel(app.ManualModeTab);
            app.ZHomeLimitLampLabel.Text = 'Z Home Limit';
            app.ZHomeLimitLamp = uilamp(app.ManualModeTab);
            app.XHomeLimitLampLabel = uilabel(app.ManualModeTab);
            app.XHomeLimitLampLabel.Text = 'X Home Limit';
            app.XHomeLimitLamp = uilamp(app.ManualModeTab);
            app.AutomaticModeTab = uitab(app.TabGroup);
            app.AutomaticModeTab.Title = 'Automatic Mode';
            app.Image6 = uiimage(app.AutomaticModeTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Sem Título6.png');
            app.Image_4 = uiimage(app.AutomaticModeTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Sem Título6.png');
            app.ReadGcodeButton = uibutton(app.AutomaticModeTab, 'push');
            app.ReadGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReadGcodeButtonPushed, true);
            app.ReadGcodeButton.Text = 'Read G-code';
            app.StartButton = uibutton(app.AutomaticModeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.AutomaticModeTab);
            app.ElapsedTimeLabel = uilabel(app.AutomaticModeTab);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.SetupLabel = uilabel(app.AutomaticModeTab);
            app.SetupLabel.Text = 'Setup';
            app.SETButton = uibutton(app.AutomaticModeTab, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.ZP_XEditFieldLabel = uilabel(app.AutomaticModeTab);
            app.ZP_XEditFieldLabel.Text = 'ZP_X';
            app.ZP_XEditField = uieditfield(app.AutomaticModeTab, 'numeric');
            app.ZP_YEditFieldLabel = uilabel(app.AutomaticModeTab);
            app.ZP_YEditFieldLabel.Text = 'ZP_Y';
            app.ZP_YEditField = uieditfield(app.AutomaticModeTab, 'numeric');
            app.ZP_ZEditFieldLabel = uilabel(app.AutomaticModeTab);
            app.ZP_ZEditFieldLabel.Text = 'ZP_Z';
            app.ZP_ZEditField = uieditfield(app.AutomaticModeTab, 'numeric');
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Image4 = uiimage(app.InfoTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Sem Título4.png');
            app.ManualModeLabel = uilabel(app.InfoTab);
            app.ManualModeLabel.Text = 'Manual Mode';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'First select the port to connect to the controller. '; 'To move the motors, it is necessary to enable the motors.'; ''; 'Choose the option to execute the "homing" routine or the option to send the end-efector '; 'to a specific point (x,y,x).'};
            app.Image4_2 = uiimage(app.InfoTab);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'Sem Título4.png');
            app.AutomaticmodeLabel = uilabel(app.InfoTab);
            app.AutomaticmodeLabel.Text = 'Automatic mode';
            app.Label_2 = uilabel(app.InfoTab);
            app.Label_2.Text = {'Start by loading the .nc file with the G-code and press "Start".'; ''; 'A stopwatch is also presented, which provides information on the time elapsed since'; ' the beginning of the part''s conformation. In addition, there is an option to perform tool '; 'compensation if the tool is positioned differently than defined by the G-code.'};
            app.Image5 = uiimage(app.InfoTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Sem Título5.png');
            app.AboutLabel = uilabel(app.InfoTab);
            app.AboutLabel.Text = 'About';
            app.Label_3 = uilabel(app.InfoTab);
            app.Label_3.Text = 'Developed by Tatiana Resende, nº 93038';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Sem Título2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end